classdef SpeedControllerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NumberOfNodes                matlab.ui.container.Panel
        NumberofNodesEditField       matlab.ui.control.NumericEditField
        NumberofNodesEditFieldLabel  matlab.ui.control.Label
        WalkModesPanel               matlab.ui.container.Panel
        RunningButton                matlab.ui.control.Button
        LockedButton                 matlab.ui.control.Button
        ScopeSelectRecordPanel       matlab.ui.container.Panel
        tStance_avg                  matlab.ui.control.Label
        tStride_avg                  matlab.ui.control.Label
        StrideStateR                 matlab.ui.control.Label
        AverageStanceTimeLabel       matlab.ui.control.Label
        AverageStrideTimeLabel       matlab.ui.control.Label
        StrideStateRightLabel        matlab.ui.control.Label
        PreExperimentPanel           matlab.ui.container.Panel
        GetMassButton                matlab.ui.control.Button
        ZeroTreadmillButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        tg              % target
        fsc             % filescope 
        % modes contains info about the discrete modes (states) we may be in
        % e.g. exoModes.prep.ankle tells which prep mode (REST/SETZERO/WALK) we're in at the ankle
        exoModes
        
        % housekeeping properties
        n_resets        % number of times IL reset (related to SPT?)
        dateTimeStart
        dir = cd
        saveDir = 'C:\Users\Vicon-OEM\Documents\RMM\repos\katee\rawData'
        saveFile = 'FileScope.dat'
        scopes = [];            % list of scopes
        
        % cma object contains CMA-ES (hyper)parameters
        % e.g. app.cma.lambda is the number of conditions per generation
        %cma
        
        % assistance object contains knee and ank min, max, mean, static, current torque parameters
        % e.g. app.assistance.knee.min is a [7x1] matrix with the min vals for the 7 parameters of knee assist
        %assistance
        
        % gains object contains all the Kp, Kd, Kl, delay parameters for ank and knee
        % e.g. app.gains.kneeFlex.Kp is the proportional gain on knee flexion
        %gains
        
        % subjInfo contains information about the subject (subjNo, dayNo, age, weight, shankNo)
        %subjInfo
        
        % tm contains info about treadmill (vel, SPTState, GRFOffsets, params)
        tm
        
        % slxConstants contains values of enumerated constants from slx model (e.g.
        % WALKMODE=2 in the slx controller, so when the mode signal is 2, we
        % know that it is walk mode)
        %slxConstants
        
        % timers
        timer_update_gui    % updates states on GUI at every DT_TIMERGUIUPDATE seconds
        timer_debug         % prints state of controller signals to matlab command window
    end
    
    properties (Constant)
        % time constants (s)
        %DT_TIMERGRFCALIBRATE = 0.05;
        DT_TIMERGUIUPDATE = 0.2;
        DT_TIMERDEBUG = 0.05;
        
        % ~~~ enumeration ~~~
        % general purpose
        OFF = 0;
        ON = 1;
        
        % joints and DoFs
        % ANK = 11; % Ankle joint
        % KNEE = 12; % Knee joint
        % KNFL = 13; % knee flexion
        % KNEX = 14; % knee extension
        % 
        % % cma or static modes used for ankle and knee (state_cma_joint)
        % MODE_CMA = 20;
        % MODE_STATIC = 21;
        % 
        % % prep mode for ankle and knee (state_prep_joint)
        % MODE_REST = 30;
        % MODE_SETZERO = 31;
        % MODE_WALK = 32;
        % 
        % % walk mode for ankle and knee (state_walk_joint)
        % MODE_ZEROTORQUE = 40;
        % MODE_TORQUE = 41;
        % 
        % ~~~ \enumeration ~~~
    end
    
    methods (Access = private)
        
        function update_constants(app)
            basePath = 'Sensor Read and Process/GUI/';
            app.slxConstants.LOCK       = getsignal(app.tg, [basePath, 'KEXT']);
            app.slxConstants.RUN      = getsignal(app.tg, [basePath, 'KFLEX']);
            app.slxConstants.REST       = getsignal(app.tg, [basePath, 'REST']);
            app.slxConstants.SETZERO    = getsignal(app.tg, [basePath, 'SETZERO']);
            app.slxConstants.SWING      = getsignal(app.tg, [basePath, 'SWING']);
            app.slxConstants.STANCE     = getsignal(app.tg, [basePath, 'STANCE']);
            app.slxConstants.TORQUE     = getsignal(app.tg, [basePath, 'TORQUE']);
            
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            format compact
            
            % start simulink. time controller build time
            app.tg = SimulinkRealTime.target;
            %app.fsc = getscope(app.tg, 5);
            
            
            % create timers
            app.timer_update_gui = timer('TimerFcn',@app.update_gui, ...
                 'Period', app.DT_TIMERGUIUPDATE , 'ExecutionMode','fixedRate','BusyMode','drop', 'Name', 'timer_update_gui');
            app.timer_debug = timer('TimerFcn',@app.print_status, ...
                'Period', app.DT_TIMERDEBUG , 'ExecutionMode','fixedRate','BusyMode','queue', 'Name', 'timer_debug');
            
            % update constants from slx file for state determination
            app.update_constants();
            % housekeeping
            app.dateTimeStart = datetime('now');
        end

        % Button pushed function: ZeroTreadmillButton
        function ZeroTreadmillButtonPushed(app, event)
            setparam(app.tg,'Sensor Read and Process/GUI/BTzeroLeft','Value',1);
            setparam(app.tg,'Sensor Read and Process/GUI/BTzeroRight','Value',1);
            pause(0.01);
            setparam(app.tg,'Sensor Read and Process/GUI/BTzeroLeft','Value',0);
            setparam(app.tg,'Sensor Read and Process/GUI/BTzeroRight','Value',0);
            
            app.ZeroTreadmillButton.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Button pushed function: GetMassButton
        function GetMassButtonPushed(app, event)
            setparam(app.tg,'Sensor Read and Process/GUI/BTmeasureWeight','Value',1);
            
            % Measure weight for predefined number of samples and add to vector
            nWeightSamples = 500;
            weightMemory = zeros(nWeightSamples,1);
            counter = 1;
            while counter <= nWeightSamples
                subjWeight = getsignal(app.tg, 'Sensor Read and Process/measureSubjWeight');
                weightMemory(counter) = subjWeight;
                counter = counter + 1;
            end
            
            % Determine subject mass
            app.subjInfo.weight = mean(weightMemory) / 9.81; % [kg]
            pause(0.01);
            setparam(app.tg,'Sensor Read and Process/GUI/BTmeasureWeight','Value',0)
            disp(['Subject mass: ', num2str(app.subjInfo.weight), ' kg']);
            setparam(app.tg, 'Sensor Read and Process/CMAES/Mass','Value', app.subjInfo.weight);
            
            app.GetMassButton.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Button pushed function: LockedButton
        function LockedButtonPushed(app, event)
            %setparam(app.tg,'Sensor Read and Process/GUI/Lock','Value',1);
            %setparam(app.tg,'Sensor Read and Process/GUI/Running','Value',0);
            app.LockedButton.BackgroundColor = [0.99,1.00,0.80];
            app.RunningButton.BackgroundColor = [1, 1, 1];
            setparam(app.tg,'Sensor Read and Process/GUI/BTLock','Value',1);
            pause(0.01);
            setparam(app.tg,'Sensor Read and Process/GUI/BTLock','Value',0);
        end

        % Button pushed function: RunningButton
        function RunningButtonPushed(app, event)
            %setparam(app.tg,'Sensor Read and Process/GUI/Lock','Value',0);
            app.LockedButton.BackgroundColor = [1, 1, 1];
            app.RunningButton.BackgroundColor = [0.99,1.00,0.80];
            setparam(app.tg,'Sensor Read and Process/GUI/BTRun','Value',1);
            pause(0.01);
            setparam(app.tg,'Sensor Read and Process/GUI/BTRun','Value',0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PreExperimentPanel = uipanel(app.UIFigure);
            app.PreExperimentPanel.Title = 'Pre-Experiment';
            app.ZeroTreadmillButton = uibutton(app.PreExperimentPanel, 'push');
            app.ZeroTreadmillButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroTreadmillButtonPushed, true);
            app.ZeroTreadmillButton.Text = 'Zero Treadmill';
            app.GetMassButton = uibutton(app.PreExperimentPanel, 'push');
            app.GetMassButton.ButtonPushedFcn = createCallbackFcn(app, @GetMassButtonPushed, true);
            app.GetMassButton.Text = 'Get Mass';
            app.ScopeSelectRecordPanel = uipanel(app.UIFigure);
            app.ScopeSelectRecordPanel.Title = 'Scope Select/Record';
            app.StrideStateRightLabel = uilabel(app.ScopeSelectRecordPanel);
            app.StrideStateRightLabel.Text = 'Stride State Right';
            app.AverageStrideTimeLabel = uilabel(app.ScopeSelectRecordPanel);
            app.AverageStrideTimeLabel.Text = 'Average Stride Time';
            app.AverageStanceTimeLabel = uilabel(app.ScopeSelectRecordPanel);
            app.AverageStanceTimeLabel.Text = 'Average Stance Time';
            app.StrideStateR = uilabel(app.ScopeSelectRecordPanel);
            app.StrideStateR.Text = '1';
            app.tStride_avg = uilabel(app.ScopeSelectRecordPanel);
            app.tStride_avg.Text = '1';
            app.tStance_avg = uilabel(app.ScopeSelectRecordPanel);
            app.tStance_avg.Text = '1';
            app.WalkModesPanel = uipanel(app.UIFigure);
            app.WalkModesPanel.Title = 'Walk Modes';
            app.LockedButton = uibutton(app.WalkModesPanel, 'push');
            app.LockedButton.ButtonPushedFcn = createCallbackFcn(app, @LockedButtonPushed, true);
            app.LockedButton.Text = 'Locked';
            app.RunningButton = uibutton(app.WalkModesPanel, 'push');
            app.RunningButton.ButtonPushedFcn = createCallbackFcn(app, @RunningButtonPushed, true);
            app.RunningButton.Text = 'Running';
            app.NumberOfNodes = uipanel(app.UIFigure);
            app.NumberOfNodes.Title = 'Panel';
            app.NumberofNodesEditFieldLabel = uilabel(app.NumberOfNodes);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodesEditField = uieditfield(app.NumberOfNodes, 'numeric');
            app.NumberofNodesEditField.Limits = [0 6];
            app.NumberofNodesEditField.RoundFractionalValues = 'on';
            app.NumberofNodesEditField.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpeedControllerGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end